classdef Wireal_1_6_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigureMain            matlab.ui.Figure
        MenuFile                matlab.ui.container.Menu
        MenuImdel               matlab.ui.container.Menu
        MenuView                matlab.ui.container.Menu
        MenuHelp                matlab.ui.container.Menu
        MenuGuide               matlab.ui.container.Menu
        MenuAbout               matlab.ui.container.Menu
        AxesMain                matlab.ui.control.UIAxes
        lstBoxState             matlab.ui.control.ListBox
        PanelNetwork            matlab.ui.container.Panel
        TextAddr                matlab.ui.control.Label
        EditIP                  matlab.ui.control.EditField
        TextPort                matlab.ui.control.Label
        EditPort                matlab.ui.control.EditField
        PanelWorkspace          matlab.ui.container.Panel
        TextDir                 matlab.ui.control.Label
        BtnSelectDir            matlab.ui.control.Button
        PanelData               matlab.ui.container.Panel
        TextNtx                 matlab.ui.control.Label
        EditNtx                 matlab.ui.control.EditField
        TextNrx                 matlab.ui.control.Label
        EditNrx                 matlab.ui.control.EditField
        TextHz                  matlab.ui.control.Label
        EditHz                  matlab.ui.control.EditField
        PanelCtrl               matlab.ui.container.Panel
        TextDetectStreamIndex   matlab.ui.control.Label
        BtnNextDetectStream     matlab.ui.control.Button
        LabelConnect            matlab.ui.control.Label
        LabelHz                 matlab.ui.control.Label
        LabelMIMO               matlab.ui.control.Label
        DispConnect             matlab.ui.control.EditField
        DispHz                  matlab.ui.control.EditField
        DispMIMO                matlab.ui.control.EditField
        PanelPlotMode           matlab.ui.container.Panel
        TextStreamIndex         matlab.ui.control.Label
        BtnNextStream           matlab.ui.control.Button
        PanelSubcarrierOptions  matlab.ui.container.Panel
        EditSubcarrierIndex     matlab.ui.control.EditField
        TextSubcarrierLeader    matlab.ui.control.Label
        DropDownSubcarrier      matlab.ui.control.DropDown
        Text8                   matlab.ui.control.Label
        EditMaxSec              matlab.ui.control.EditField
        Text9                   matlab.ui.control.Label
        BtnStart                matlab.ui.control.Button
        BtnContinue             matlab.ui.control.Button
        BtnPause                matlab.ui.control.Button
        CheckboxTemp            matlab.ui.control.CheckBox
        BtnCut                  matlab.ui.control.Button
        Text25                  matlab.ui.control.Label
        EditAxisLow             matlab.ui.control.EditField
        Text26                  matlab.ui.control.Label
        EditAxisHigh            matlab.ui.control.EditField
        TextTempWarn            matlab.ui.control.Label
        CheckboxVitalDetect     matlab.ui.control.CheckBox
        LabelRunningTime        matlab.ui.control.Label
        TextRunningTime         matlab.ui.control.Label
        PlotModeSwitchLabel     matlab.ui.control.Label
        PlotModeSwitch          matlab.ui.control.Switch
        TabGroup                matlab.ui.container.TabGroup
        VitalSignsTab           matlab.ui.container.Tab
        AxesRes                 matlab.ui.control.UIAxes
        AxesHeart               matlab.ui.control.UIAxes
        TextBreathRateTag       matlab.ui.control.Label
        TextResRateValue        matlab.ui.control.Label
        TextHeartbeatRateTag    matlab.ui.control.Label
        TextHeartbeatRateValue  matlab.ui.control.Label
        TextResStatics          matlab.ui.control.Label
        TextHeartbeatStatics    matlab.ui.control.Label
        VoiceTab                matlab.ui.container.Tab
    end

    
    properties (Access = private)
        mainTimer                     % system timer object
        havntBeenStarted              % to indicate whether it has been started
        filename
        fileHandle                    % file handle of the data file
        curPos                        % Current position of file read pointer.
        paused
        pausedPlotData                % saved plotData when system is paused.
        pausedPlotX                   % saved plotX when system is paused.
        pausedRealNum
        haveSelectedDir
        workingDir
        adapterName
        Ntx
        Nrx
        waitedPackNum                 % how many packs have come (mainly for debug use)
        everyPackSec
        dataFre                       % data frequency
        ip                            % listen ip address
        server_ip                     % ip of server(this machine).
        port
        plotMaxSec                    % how many packs (in secs) will be plotted
        plotGapNum
        plotData
        plotMode                      % plot mode, 's' means split, 'c' means combine
        splitIndex
        detectIndex
        subcarrierIndex
        axisLow
        axisHigh
        time_begin2draw               % the time when data begin to draw
    end
    
    properties (Access = public)
        havBeenCut                    % have been cut times
        lastCutNoFile        
        dateFormat
        blankFormat 
        lstBoxCacheThreshold          % controls how often to clear listbox       
    end
    
    methods (Access = private)
        function mainTimerStartFcn(app, ~, ~)
            
            if (app.havntBeenStarted == true)
                tStr = datestr(now, 30);
                timestamp = [tStr(1 : 4), '-', tStr(5 : 6), '-', tStr(7 : 8), '--', tStr(10 : 11), '-', tStr(12 : 13), '-', tStr(14 : 15)];
                workingDir = app.workingDir;
                address = app.ip;
                port = app.port;
                filename = fullfile(workingDir, timestamp);
                tcp_recv('csi_tcp_recv', address, port, filename);
                app.filename = filename;
                app.havntBeenStarted = false;
                
                updateLstBoxState(app, 'Ready for collecting data...');
            end
        end
        
        function mainTimerRunningFcn(app, ~, ~)
            
            filename = app.filename;
            if exist(filename, 'file') == 0
                return;
            end
            
            % When program runs at this point that means data have come
            % We can roughly say this moment is the beginning of running time
            % Be careful to initialize begin2draw time only once (preset defalt value
            % -1)
            
            if app.time_begin2draw == -1
                app.time_begin2draw = 0;
                tic;
                %myHandles.Info.cputime_begin2draw = cputime;
            end
            
            fhand = app.fileHandle;
            % -1 represents the default value of fileHandle
            if fhand == -1
                fhand = fopen(filename, 'rb');
                % After excuting fopen, errors will come when fhand is less than 0.
                if (fhand < 0)
                    fprintf('Couldn''t open file %s', filename);
                    %toc;
                    return;
                end
                app.fileHandle = fhand;
            end
            
            Ntx = app.Ntx;
            Nrx = app.Nrx;
            
            % More details in read_bfee.c
            % sizeof(csi) = floor((30 * (Ntx * Nrx * 8 * 2 + 3) + 7) / 8)
            % sizeof(other contents) = 20 (eg. from inBytes[0] to inBytes[19], 20 bytes in total)
            % 3 = 2 (2 bytes size) + 1 (1 byte code)
            
            thresholdSize = floor((30 * (Ntx * Nrx * 8 * 2 + 3) + 7) / 8) + 20 + 3;
            
            %Something wrong with dir(filename).bytes, may it is unsafe for IO.
            %dirInfo = dir(filename);
            %fileSize = dirInfo.bytes;
            
            fseek(fhand, 0, 'eof');
            fileSize = ftell(fhand);
            cur = app.curPos;
            fseek(fhand, cur, 'bof');
            recvSize = fileSize - cur;
            if (recvSize < thresholdSize) && (app.paused == false)
                clearLstBoxStateCache(app);
                updateLstBoxState(app, sprintf('No more than %d bytes!', thresholdSize));
                app.TextRunningTime.Text = sec2dhms(toc);
                %myHandles.textRunningTime.String = sec2dhms(cputime - myHandles.Info.cputime_begin2draw);
                %toc;
                return;
            end
            [pack, cur] = read_bf_file_real_time(fhand, cur, recvSize, Ntx, Nrx);
            
            % Attention this function will slow down the frame and make system lose real-time property.
            %validateCorrectness(pack);
            
            % Update curPos
            app.curPos = cur;
            % If no valid data retrieved, pack will be set to cell(1, 2)
            if size(pack, 2) == 2
                if app.paused == false
                    clearLstBoxStateCache(app);
                    updateLstBoxState(app, sprintf('Received %d bytes but no valid contents...', recvSize));
                end
                app.TextRunningTime.Text = sec2dhms(toc);
                %myHandles.textRunningTime.String = sec2dhms(cputime - myHandles.Info.cputime_begin2draw);
                %toc;
                return;
            end
            % Extract CSI from raw data matrix.
            array = adjust_CSI(pack, Ntx, Nrx, 30);
            if strcmp(app.DropDownSubcarrier.Value, '1')
                array = getAverageCSI(array, 30);
            else
                array = getSubcarrierCSI(array, 30, app.subcarrierIndex);
            end
            
            % temporary variable, remember to update original value
            plotData = app.plotData;
            plotGapNum = app.plotGapNum;
            plotMaxPack = size(plotData, 2);
            packNum = size(array, 2);
            if (plotGapNum == 0)
                plotData = [plotData(:, packNum + 1 : end), array];
            elseif (packNum <= plotGapNum)
                currentGapPos = plotMaxPack - plotGapNum + 1;
                plotData(:, currentGapPos : currentGapPos + packNum - 1) = array;
                plotGapNum = plotGapNum - packNum;
            elseif (packNum >= plotMaxPack)
                plotData = array(packNum - plotMaxPack + 1 : end);
                plotGapNum = 0;
            else
                plotData = [plotData(:, packNum - plotGapNum + 1 : end - plotGapNum), array];
                plotGapNum = 0;
            end
            
            % update original value
            app.plotData = plotData;
            app.plotGapNum = plotGapNum;
            realNum = plotMaxPack - plotGapNum;
            %realNum = plotMaxPack;
            
            if (plotGapNum == 0)
                plotX = (1 : plotMaxPack) * app.everyPackSec;
            else
                plotX = (plotMaxPack - realNum + 1 : plotMaxPack) * app.everyPackSec;
            end
            
            % Set function is better since you still obtain focus.
            %set(gcf, 'CurrentAxes', myHandles.axes1);
            %myHandles.figure1.CurrentAxes = myHandles.axes1;
            %axes(myHandles.axes1);
            % axis([0, myHandles.Info.plotMaxSec, myHandles.Info.axisLow, myHandles.Info.axisHigh]);
            % If is in the pause state, only draw the pause time figure(why draw? for control).
            if (app.paused == true)
                if isempty(app.pausedPlotData)
                    app.pausedPlotData = plotData;
                    app.pausedPlotX = plotX;
                    app.pausedRealNum = realNum;
                else
                    plotData = app.pausedPlotData;
                    plotX = app.pausedPlotX;
                    realNum = app.pausedRealNum;
                end
            end
            cla(app.AxesMain);
            hold(app.AxesMain, 'on');
            if (app.plotMode == 'c')
                tag = strings(1, Ntx * Nrx);
                for i = 1 : size(plotData, 1)
                    % Camera from right(oldest) to left(newest):
                    %plot(plotX, plotData(i, realNum : -1 : 1));
                    plot(app.AxesMain, plotX, plotData(i, 1 : realNum));
                    tag(:, i) = sprintf('Spatial Stream  %d', i);
                end
                legend(app.AxesMain, tag, 'Location', 'SouthEast');
                %drawnow update;
            % else plotMode == 's'
            else
                splitIndex = app.splitIndex;
                % Camera from right(oldest) to left(newest):
                %plot(plotX, plotData(splitIndex, realNum : -1 : 1), 'r');
                plot(app.AxesMain, plotX, plotData(splitIndex, 1 : realNum), 'r');
                legend(app.AxesMain, sprintf('Spatial Stream  %d', splitIndex), 'Location', 'SouthEast');
            end
            hold(app.AxesMain, 'off');
            
            % For pause state control we can't use plotGapNum to determine if data is
            % full of the axis, cause at pause state plotGapNum belongs to real time
            % data
            %if (plotGapNum == 0) && get(myHandles.checkboxVitalDetect, 'Value')
            
            if get(app.CheckboxVitalDetect, 'Value') && (plotMaxPack == realNum)
                
                detectIndex = app.detectIndex;
                intered_data = interpolation_data(plotData);
                frequency = app.dataFre;
                
                %approximated_sec_pass = packNum / frequency;
                %shift_sec = myHandles.Info.shift_sec + approximated_sec_pass;
                % Respiration
                
                res_data = butterFilter_realtime(intered_data, frequency, 0);
                res_rate = getVitalRate(res_data, frequency, 0);
                
                app.TextResStatics.Visible = 'on';
                app.TextResStatics.Text = rate2StaticsStr(res_rate);
                %selected_res_rate = removeMaxAndMinThenMean(res_rate);
                selected_res_rate = res_rate(1, detectIndex);
                
                %app.TextResRateValue.Text = sprintf('%d', selected_res_rate);
                app.TextResRateValue.Text = sprintf('%0.2f', selected_res_rate);
                app.TextResRateValue.FontColor = 'red';
                
                cla(app.AxesRes);
                hold(app.AxesRes, 'on');
                
                % Camera from right to left:
                %plot(plotX, res_data(detectIndex, realNum : -1 : 1), 'y');
                
                % Camera from left to right:
                plot(app.AxesRes, plotX, res_data(detectIndex, 1 : realNum), 'y');
                hold(app.AxesRes, 'off');
                
                % Heartbeat
                heart_data = butterFilter_realtime(intered_data, frequency, 1);
                heart_rate = getVitalRate(heart_data, frequency, 1);
                
                app.TextHeartbeatStatics.Text = rate2StaticsStr(heart_rate);
                app.TextHeartbeatStatics.Visible = 'on';
                %selected_heart_rate = removeMaxAndMinThenMean(heart_rate);
                selected_heart_rate = heart_rate(1, detectIndex);
                
                %app.TextHeartbeatRateValue.Text = sprintf('%d', selected_heart_rate);
                app.TextHeartbeatRateValue.Text = sprintf('%0.2f', selected_heart_rate);
                app.TextHeartbeatRateValue.FontColor = 'red';
                
                cla(app.AxesHeart);
                hold(app.AxesHeart, 'on');
                
                % Camera from right to left:
                %plot(plotX, heart_data(detectIndex, realNum : -1 : 1), 'g');
                
                % Camera from left to right:
                plot(app.AxesHeart, plotX, heart_data(detectIndex, 1 : realNum), 'g');
                hold(app.AxesHeart, 'off');
                %myHandles.Info.shift_sec = shift_sec;
            end
            if app.paused == false
            	clearLstBoxStateCache(app);
                updateLstBoxState(app, sprintf('Received %d bytes...', recvSize));
            end
            %
            %pause(0.000001);% drawnow;
            % drawnow update;
            app.TextRunningTime.Text = sec2dhms(toc);
            %myHandles.textRunningTime.String = sec2dhms(cputime - myHandles.Info.cputime_begin2draw);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function uiFigureMainStartupFcn(app)
            
            % maximize           
            useWindowAPI2Maximize(app.UIFigureMain);
            
            % For Application Logo(top left).
%             imshow('logo.jpg', app.UIFigureMain);

            % init common properties
            app.havntBeenStarted = true; 
            app.havBeenCut = 0;
            app.lastCutNoFile = false;
            app.filename = 'test';
            app.fileHandle = -1;
            app.curPos = 0;    
            app.paused = false;
            app.pausedPlotData = [];  
            app.pausedPlotX = [];  
            app.pausedRealNum = -1;
            app.dateFormat = 'mm/dd HH:MM:SS:FFF';
            app.blankFormat = blanks(8);
            app.haveSelectedDir = false;
            app.workingDir = matlabroot;
            app.adapterName = 'Ethernet';
            app.Ntx = str2double(app.EditNtx.Value);
            app.Nrx = str2double(app.EditNrx.Value);
            app.waitedPackNum = 0;
            app.everyPackSec = 1 / str2double(app.EditHz.Value);
            app.dataFre = str2double(app.EditHz.Value);
            app.ip = app.EditIP.Value;
            app.server_ip = '192.168.8.88';
            app.port = app.EditPort.Value;
            app.plotMaxSec = str2double(app.EditMaxSec.Value);
            app.plotGapNum = 0;
            app.plotData = [];
            app.plotMode = 'c';
            app.splitIndex = str2double(app.TextStreamIndex.Text);
            app.detectIndex = str2double(app.TextDetectStreamIndex.Text);
            app.subcarrierIndex = str2double(app.EditSubcarrierIndex.Value);
            app.axisLow = str2double(app.EditAxisLow.Value);
            app.axisHigh = str2double(app.EditAxisHigh.Value);
            app.time_begin2draw = -1;
            app.lstBoxCacheThreshold = 500;
        
            % init timer object
            app.mainTimer = timer(...
                'Period', 0.05, ...
                'StartDelay', 0, ...
                'TasksToExecute', inf, ...
                'ExecutionMode', 'fixedSpacing', ...
                'StartFcn', @app.mainTimerStartFcn, ...
                'TimerFcn', @app.mainTimerRunningFcn);
            
            axis(app.AxesMain, [0, app.plotMaxSec, app.axisLow, app.axisHigh]);
            
            xlim(app.AxesRes, [0, app.plotMaxSec]);            
            xticks(app.AxesRes, 'auto');
            yticks(app.AxesRes, 'auto');
            
            xlim(app.AxesHeart, [0, app.plotMaxSec]);
            xticks(app.AxesHeart, 'auto');
            yticks(app.AxesHeart, 'auto');
        end

        % Button pushed function: BtnContinue
        function BtnContinue_Callback(app, event)

            app.BtnPause.Visible = 'on';
%           %set(hObject, 'Visible', 'off');
            app.BtnContinue.Visible = 'off';
            %set(app.BtnNextStream, 'Enable', 'on');
            app.paused = false;
            app.pausedPlotData = [];
            app.pausedPlotX = [];
            app.pausedRealNum = -1;
            
            updateLstBoxState(app, 'Continue...');
            %start(app.timer);
        end

        % Button pushed function: BtnCut
        function BtnCut_Callback(app, event)

            % shutdown tcp connection
            tcp_recv('csi_tcp_recv');
            
            % close file
            fclose('all');
            
            % stop timer
            stop(app.mainTimer);
            
            % clear axes
            cla(app.AxesMain);
            cla(app.AxesRes);
            cla(app.AxesHeart);
            
            clearLstBoxStateCache(app, 'cut');
                               
            havBeenCutTimes = app.havBeenCut;
%             if havBeenCutTimes == 0
%                 app.lstBoxState.Value = {};
%                 app.lstBoxState.Items = {};
%             else
%                 if app.lastCutNoFile == false
%                     lastFileRecord = cell2mat(app.lstBoxState.Items(havBeenCutTimes));
%                     lastFileRecord = cat(2, lastFileRecord, '   <HISTORY>');
%                     app.lstBoxState.Items(havBeenCutTimes) = {lastFileRecord};
%                 end
%                 app.lstBoxState.Items = app.lstBoxState.Items(1:havBeenCutTimes);
%                 app.lstBoxState.Value = app.lstBoxState.Items(end);
%             end
            
            filename = app.filename;
            if exist(filename, 'file')
                app.lastCutNoFile = false;
                if app.CheckboxTemp.Value
                    system(['del ', filename]);
                    updateLstBoxState(app, ['"', filename, '"', '  has been cut and deleted.']);
                else
                    updateLstBoxState(app, ['"', filename, '"', '  has been cut and saved.']);
                end
                app.havBeenCut = havBeenCutTimes + 1;
            else
                app.lastCutNoFile = true;
                updateLstBoxState(app, ['"', filename, '"', '  doesn''''t exist.']);
            end
            
            app.havntBeenStarted = true;
            app.plotGapNum = app.plotMaxSec * app.dataFre;
            app.curPos = 0;
            app.fileHandle = -1;
            app.time_begin2draw = -1;
            app.paused = false;
            app.pausedPlotData = [];
            app.pausedPlotX = [];
            app.pausedRealNum = -1;
            
            %app.Info.shift_sec = 0;
            
%             set(hObject, 'Visible', 'off');
            app.BtnCut.Visible = 'off';
            app.BtnPause.Visible = 'off';
            app.BtnContinue.Visible = 'off';
            %set(app.BtnNextStream, 'Enable', 'on');
            app.LabelConnect.Visible = 'off';
            app.LabelHz.Visible = 'off';
            app.LabelMIMO.Visible = 'off';
            app.LabelRunningTime.Visible = 'off';
            app.TextRunningTime.Visible = 'off';
            app.DispConnect.Visible = 'off';
            app.DispHz.Visible = 'off';
            app.DispMIMO.Visible = 'off';
            
            app.BtnStart.Visible = 'on';
            app.EditSubcarrierIndex.Enable = 'on';
            app.DropDownSubcarrier.Enable = 'on';
            app.BtnSelectDir.Enable = 'on';
            app.EditPort.Enable = 'on';
            app.EditIP.Enable = 'on';
            app.EditHz.Enable = 'on';
            app.EditNtx.Enable = 'on';
            app.EditNrx.Enable = 'on';
            app.EditMaxSec.Enable = 'on';
            app.CheckboxVitalDetect.Enable = 'on';
            
            if app.CheckboxVitalDetect.Value
                app.TextResRateValue.Text = 'Ready';
                app.TextHeartbeatRateValue.Text = 'Ready';
                app.TextResRateValue.FontColor = [0, 0.8, 0.2];
                app.TextHeartbeatRateValue.FontColor = [0, 0.8, 0.2];
            else
                app.TextResRateValue.Text = 'Suspend';
                app.TextHeartbeatRateValue.Text = 'Suspend';
                app.TextResRateValue.FontColor = 'black';
                app.TextHeartbeatRateValue.FontColor = 'black';
            end
            
            app.TextResStatics.Visible = 'off';
            app.TextResStatics.Text = '';
            app.TextHeartbeatStatics.Visible = 'off';
            app.TextHeartbeatStatics.Text = '';
            app.TextRunningTime.Text = '0 d  0 h  0 m  0 s';
        end

        % Button pushed function: BtnNextDetectStream
        function BtnNextDetectStream_Callback(app, event)
           
            detectIndex = app.detectIndex;
            if (detectIndex == app.Ntx * app.Nrx)
                app.detectIndex = 1;
            else
                app.detectIndex = detectIndex + 1;
            end
            app.TextDetectStreamIndex.Text = sprintf('%d', app.detectIndex);
        end

        % Button pushed function: BtnNextStream
        function BtnNextStream_Callback(app, event)
            
            splitIndex = app.splitIndex;
            if (splitIndex == app.Ntx * app.Nrx)
                app.splitIndex = 1;
            else
                app.splitIndex = splitIndex + 1;
            end
            app.TextStreamIndex.Text = sprintf('%d', app.splitIndex);
        end

        % Button pushed function: BtnPause
        function BtnPause_Callback(app, event)
            
            app.BtnContinue.Visible = 'on';
%           %set(hObject, 'Visible', 'off');
            app.BtnPause.Visible = 'off';
            %set(app.BtnNextStream, 'Enable', 'off');
            app.paused = true;
            updateLstBoxState(app, 'Paused...');
            %stop(app.timer);
            %disp('stoped');
        end

        % Button pushed function: BtnSelectDir
        function BtnSelectDir_Callback(app, event)

            if app.haveSelectedDir == false
                server_ip = app.server_ip;
                adapterName = app.adapterName;
                
                [admin_check_status, ~] = system('net session >nul 2>&1');
                if admin_check_status ~= 0
                    %warndlg('Please run as administrator!', 'WARNING');
                    %errordlg('Please run as administrator.', 'Permission Error');
                    uialert(app.UIFigureMain, 'Please run as administrator.', 'Permission Error');
                    return;
                end
                
                % Attention, here is findstr"onnected", will match "disconnected" and
                % "Connected".
                %[check_adapter_name_status, ~] = system(sprintf('netsh interface show interface name="%s" | findstr "onnected"', adapterName));
                
                [check_adapter_name_status, ~] = system(sprintf('chcp 437 && netsh interface show interface name="%s" | findstr "onnected"', adapterName));
                if check_adapter_name_status ~= 0
                    %warndlg(sprintf('Please rename your LAN adapter to "%s"', adapterName), 'WARNING');
                    %errordlg(sprintf('Please rename your LAN adapter to "%s".', adapterName), 'Adapter Missed Error');
                    uialert(app.UIFigureMain, sprintf('Please rename your LAN adapter to "%s".', adapterName), 'Adapter Missed Error');
                    return;
                end
            end
            
            %if (strcmp(get(app.timer, 'Running'), 'on'))
            %    warndlg('Cannot select working directory while detecting!', 'WARNING');
            %    return;
            %end
            
            workDir = uigetdir(matlabroot, 'Select working directory');
            if (workDir == 0)
                return;
            end
            
            app.TextDir.Text = workDir;
            if app.haveSelectedDir == false
                while true
                    
                    %[~, check_connected_result] = system(sprintf('netsh interface show interface name="%s" | findstr "Connected"', adapterName));
                    [check_connected_status, ~] = system(sprintf('chcp 437 && netsh interface show interface name="%s" | findstr "Connected"', adapterName));
                    if check_connected_status ~= 0
                   
                        %btn = questdlg(sprintf('Adapter "%s" is disconnected, do you want to continue to run in offline simulation mode?', adapterName), 'Continue or Retry', 'OK', 'Retry', 'Retry');
                        btn = uiconfirm(app.UIFigureMain, sprintf('Adapter "%s" is disconnected, do you want to continue to run in offline simulation mode?', adapterName), 'Continue or Retry', 'Options', {'OK', 'Retry'}, 'DefaultOption', 2);                        
                        switch btn
                            case 'OK'
                                updateLstBoxState(app, 'Run in offline simulation mode successfully...');
                                app.PanelNetwork.Visible = 'off';
                                break;
                            case 'Retry'
                                continue;
                        end
                    else
                        system([sprintf('netsh int ip set address "%s" static ', adapterName), server_ip]);
                        updateLstBoxState(app, ['IPv4 address has been set to ', server_ip, ' successfully...']);
                        break;
                    end
                end
            end
            %app.Info.filename = workDir;
            app.haveSelectedDir = true;
            app.workingDir = workDir;
        end

        % Button pushed function: BtnStart
        function BtnStart_Callback(app, event)

            if (app.haveSelectedDir == false)
                %warndlg('Haven''t selected working directory.', 'Warning');
                uialert(app.UIFigureMain, 'Please select working directory.', 'Invalid Working Directory', 'Icon', 'warning'); 
                return;
            end
            
            app.LabelConnect.Visible = 'on';
            app.LabelHz.Visible = 'on';
            app.LabelMIMO.Visible = 'on';
            app.LabelRunningTime.Visible = 'on';
            app.TextRunningTime.Visible = 'on';
            app.DispConnect.Visible = 'on';
            app.DispHz.Visible = 'on';
            app.DispMIMO.Visible = 'on';
            
            plotMaxSec = app.plotMaxSec;
            dataFre = app.dataFre;
            streamCnt = app.Ntx * app.Nrx;
            app.everyPackSec = 1 / dataFre;
            app.plotGapNum = plotMaxSec * dataFre;
            app.plotData = zeros(streamCnt, app.plotGapNum);
            
            updateLstBoxState(app, 'Waited socket connection...');
            
            app.DispConnect.Value = sprintf('%s : %s', app.ip, app.port);
            app.DispHz.Value = sprintf('%d', app.dataFre);
            app.DispMIMO.Value = sprintf('Ntx = %d   Nrx = %d', app.Ntx, app.Nrx);
            
            % update UI immediately
            %pause(0.000001);
%             set(hObject, 'Visible', 'off');
            app.BtnStart.Visible = 'off';
            app.EditSubcarrierIndex.Enable = 'off';
            app.DropDownSubcarrier.Enable = 'off';
            app.BtnSelectDir.Enable = 'off';
            app.BtnPause.Visible = 'on';
            app.BtnCut.Visible = 'on';
            app.EditHz.Enable = 'off';
            app.EditPort.Enable = 'off';
            app.EditIP.Enable = 'off';
            app.EditMaxSec.Enable = 'off';
            app.EditNtx.Enable = 'off';
            app.EditNrx.Enable = 'off';
            app.CheckboxVitalDetect.Enable = 'off';
            start(app.mainTimer);
        end

        % Value changed function: CheckboxTemp
        function CheckboxTemp_Callback(app, event)
            
            if app.CheckboxTemp.Value
                app.TextTempWarn.Visible = 'on';
            else
                app.TextTempWarn.Visible = 'off';
            end
        end

        % Value changed function: CheckboxVitalDetect
        function CheckboxVitalDetect_Callback(app, event)

            if app.CheckboxVitalDetect.Value
                app.TextResRateValue.Text = 'Ready';
                %app.TextResRateValue.ForegroundColor = 'g';
                app.TextResRateValue.FontColor = [0, 0.8, 0.2];
                app.TextHeartbeatRateValue.Text = 'Ready';
                %app.TextHeartbeatRateValue.ForegroundColor = 'g';
                app.TextHeartbeatRateValue.FontColor = [0, 0.8, 0.2];
                app.TextDetectStreamIndex.Visible = 'on';
                app.BtnNextDetectStream.Visible = 'on';
                %set(app.TextResRateValue, 'Visible', 'on');
                %set(app.TextHeartbeatRateValue, 'Visible', 'on');
            else
                app.TextResRateValue.Text = 'Suspend';
                app.TextResRateValue.FontColor = 'black';
                app.TextHeartbeatRateValue.Text = 'Suspend';
                app.TextHeartbeatRateValue.FontColor = 'black';
                app.TextDetectStreamIndex.Visible = 'off';
                app.BtnNextDetectStream.Visible = 'off';
                %set(app.TextResRateValue, 'Visible', 'off');
                %set(app.TextHeartbeatRateValue, 'Visible', 'off');
            end
        end

        % Value changed function: EditAxisHigh
        function EditAxisHigh_Callback(app, event)
            
            EditAxisHigh = app.EditAxisHigh.Value;
            res = str2double(EditAxisHigh);
            axisLow = app.axisLow;
            if (isnan(res) || res <= 0 || res > 999999999 || res <= axisLow)
                %warndlg('Invalid Axis Upper Bound : Must be a positive decimal and greater than the lower bound of axis', 'WARNING');
                %errordlg('Invalid Axis Upper Bound : Must be a positive decimal or integer and greater than the lower bound of axis and less than 999999999.', 'Input Error');
                uialert(app.UIFigureMain, 'Invalid Axis Upper Bound : Must be a positive decimal or integer and greater than the lower bound of axis and less than 999999999.', 'Input Error');
                app.EditAxisHigh.Value = sprintf('%d', app.axisHigh);
                res = app.axisHigh;
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                if rem(res, 1) == 0
                    app.EditAxisHigh.Value = sprintf('%d', res);
                end
                app.axisHigh = res;
            end
            ylim(app.AxesMain, [axisLow res]);
            yticks(app.AxesMain, 'auto');
        end

        % Value changed function: EditAxisLow
        function EditAxisLow_Callback(app, event)
           
            EditAxisLow = app.EditAxisLow.Value;
            res = str2double(EditAxisLow);
            axisHigh = app.axisHigh;
            if (isnan(res) || res < 0 || res >= axisHigh || imag(res))
                %warndlg('Invalid Axis Lower Bound : Must be a decimal above or equal 0 and less than the upper bound of axis', 'WARNING');
            	%errordlg('Invalid Axis Lower Bound : Must be a decimal or an integer above or equal 0 and less than the upper bound of axis.', 'Input Error', 'nonmodal');
             	uialert(app.UIFigureMain, 'Invalid Axis Lower Bound : Must be a decimal or an integer above or equal 0 and less than the upper bound of axis.', 'Input Error');               
                app.EditAxisLow.Value = sprintf('%d', app.axisLow);
                res = app.axisLow;
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                if rem(res, 1) == 0
                    app.EditAxisLow.Value = sprintf('%d', res);
                end
                app.axisLow = res;
            end
            ylim(app.AxesMain, [res axisHigh]);
            yticks(app.AxesMain, 'auto');
        end

        % Value changed function: EditHz
        function EditHz_Callback(app, event)
            
            TextHz = app.EditHz.Value;
            res = str2double(TextHz);
            if (isnan(res) || res < 1 || res > 999999999 || rem(res, 1) ~= 0)
                %warndlg('Invalid data frequency : Must be an integer above 0', 'WARNING');
                %errordlg('Invalid data frequency : Must be an integer between 1 to 999999999.', 'Input Error');
                uialert(app.UIFigureMain, 'Invalid data frequency : Must be an integer between 1 to 999999999.', 'Input Error');
                app.EditHz.Value = sprintf('%d', app.dataFre);
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                app.EditHz.Value = sprintf('%d', res);
                app.dataFre = res;
            end
        end

        % Value changed function: EditIP
        function EditIP_Callback(app, event)

            %        str2double(get(hObject,'String')) returns contents of editIP as a double           
            textIP = app.EditIP.Value;
            app.ip = textIP;
        end

        % Value changed function: EditMaxSec
        function EditMaxSec_Callback(app, event)
            
            lenTxt = app.EditMaxSec.Value;
            res = str2double(lenTxt);
            if (isnan(res) || res < 1 || res > 999999999 || rem(res, 1) ~= 0)
                %warndlg('Max Length Invalid: Must be an integer above 0!', 'WARNING');
                %errordlg('Max Length Invalid : Must be an integer between 1 to 999999999.', 'Input Error');
                uialert(app.UIFigureMain, 'Max Length Invalid : Must be an integer between 1 to 999999999.', 'Input Error');
                formerMaxSec = app.plotMaxSec;
                app.EditMaxSec.Value = sprintf('%d', formerMaxSec);
                res = formerMaxSec;
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                app.EditMaxSec.Value = sprintf('%d', res);
                app.plotMaxSec = res;
            end
            xlim(app.AxesMain, [0 res]);
            xticks(app.AxesMain, 'auto');
            xlim(app.AxesRes, [0 res]);
            xticks(app.AxesRes, 'auto');
            xlim(app.AxesHeart, [0 res]);
            xticks(app.AxesHeart, 'auto');
        end

        % Value changed function: EditNrx
        function EditNrx_Callback(app, event)
            
            TextNrx = app.EditNrx.Value;
            res = str2double(TextNrx);
            if (isnan(res) || res < 1 || res > 999999999 || rem(res, 1) ~= 0)
                %warndlg('Invalid Nrx number : Must be an integer above 0', 'WARNING');
                %errordlg('Invalid Nrx number : Must be an integer between 1 to 999999999.', 'Input Error');
                uialert(app.UIFigureMain, 'Invalid Nrx number : Must be an integer between 1 to 999999999.', 'Input Error');
                app.EditNrx.Value = sprintf('%d', app.Nrx);
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                app.EditNrx.Value = sprintf('%d', res);
                app.Nrx = res;
                % Initialize necessary value to make sure 'roll' button works correctly.
                app.splitIndex = 1;
                app.detectIndex = 1;
                app.TextStreamIndex.Text = '1';
                app.TextDetectStreamIndex.Text = '1';
            end
        end

        % Value changed function: EditNtx
        function EditNtx_Callback(app, event)
            
            TextNtx = app.EditNtx.Value;
            res = str2double(TextNtx);
            if (isnan(res) || res < 1 || res> 999999999 || rem(res, 1) ~= 0)
                %warndlg('Invalid Ntx number : Must be an integer above 0', 'WARNING');
                %errordlg('Invalid Ntx number : Must be an integer between 1 to 999999999.', 'Input Error');
                uialert(app.UIFigureMain, 'Invalid Ntx number : Must be an integer between 1 to 999999999.', 'Input Error');
                app.EditNtx.Value = sprintf('%d', app.Ntx);
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                app.EditNtx.Value = sprintf('%d', res);
                app.Ntx = res;
                % Initialize necessary value to make sure 'roll' button works correctly.
                app.splitIndex = 1;
                app.detectIndex = 1;
                app.TextStreamIndex.Text = '1';
                app.TextDetectStreamIndex.Text = '1';
            end
        end

        % Value changed function: EditPort
        function EditPort_Callback(app, event)
            
            TextPort = app.EditPort.Value;
            res = str2double(TextPort);
            if (isnan(res) || res < 1 || res > 65535 || rem(res, 1) ~= 0)
                %warndlg('Invalid Port : Must be an integer between 1 to 65535', 'WARNING');
                %errordlg('Invalid Port : Must be an integer between 1 to 65535.', 'Input Error');
                uialert(app.UIFigureMain, 'Invalid Port : Must be an integer between 1 to 65535.', 'Input Error');
                app.EditPort.Value = app.port;
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                app.EditPort.Value = sprintf('%d', res);
                app.port = app.EditPort.Value;
            end
        end

        % Value changed function: EditSubcarrierIndex
        function EditSubcarrierIndex_Callback(app, event)
            
            subcarrierIndex = app.EditSubcarrierIndex.Value;
            res = str2double(subcarrierIndex);
            if (isnan(res) || res < 1 || res > 30 || rem(res, 1) ~= 0)
                %warndlg('Invalid subcarrier index : Must be an integer between 1 to 30', 'WARNING');
                %errordlg('Invalid subcarrier index : Must be an integer between 1 to 30.', 'Input Error');
                uialert(app.UIFigureMain, 'Invalid subcarrier index : Must be an integer between 1 to 30.', 'Input Error');
                app.EditSubcarrierIndex.Value = sprintf('%d', app.subcarrierIndex);
            else
                % When input is a decimal but its decimal part only zero, reducing
                % decimal part.
                app.EditSubcarrierIndex.Value = sprintf('%d', res);
                app.subcarrierIndex = res;
            end
        end

        % Close request function: UIFigureMain
        function UIFigureMainCloseRequestFcn(app, event)
            
            stop(app.mainTimer);
            %btn = questdlg('Are you sure you want to exit?', 'Exit or Not', 'OK', 'Cancel', 'Cancel');
            btn = uiconfirm(app.UIFigureMain, 'Close Wireal?', 'Confirm Close', 'Icon', 'warning');
            switch btn
                case 'OK'
                    % Close tcp receive service
                    % delete(app.mainTimer);
                    delete(timerfindall);
                    fclose('all');
                    if strcmp(app.BtnStart.Visible, 'off')
                        tcp_recv('csi_tcp_recv');
                        if app.CheckboxTemp.Value
                            system(['del ', app.filename]);
                        end
                    end
                    
                    % If running in online mode...
                    adapterName = app.adapterName;
                    if strcmp(app.PanelNetwork.Visible, 'on') && app.haveSelectedDir
                    	while (true)
                            %[~, check_connected_result] = system(sprintf('netsh interface show interface name="%s" | findstr "Connected"', adapterName));
                            [check_connected_status, ~] = system(sprintf('chcp 437 && netsh interface show interface name="%s" | findstr "Connected"', adapterName));
                            if check_connected_status ~= 0
                                %questdlg(sprintf('Adapter "%s" is disconnected, you need to plug in the cable to recover DHCP service.', adapterName), 'Adapter Unpluged', 'Retry', 'Retry');
                                selection = uiconfirm(app.UIFigureMain, sprintf('Adapter "%s" is disconnected, you need to plug in the cable to recover DHCP service.', adapterName), 'Adapter Unpluged', 'Options', {'Retry'});
                                continue;
                            else
                            	system(sprintf('netsh int ip set address "%s" dhcp', adapterName));
                                updateLstBoxState(app, 'IPv4 address has been set to DHCP successfully...');
                                pause(1);
                                break;
                            end
                        end
                    end
                    
                    delete(app);
                    
                case 'Cancel'
                    if strcmp(app.BtnStart.Visible, 'off')
                        start(app.mainTimer);
                    end
                    %return;
            end
        end

        % Menu selected function: MenuImdel
        function MenuImdel_Callback(app, event)
            
            [~, ~] = uigetfile({'*.mat;','MATLAB Files(*.mat)';'*.*', ...
                'AllFiles(*.*)'},'Select an model');
        end

        % Value changed function: DropDownSubcarrier
        function DropDownSubcarrier_Callback(app, event)
            
            if strcmp(app.DropDownSubcarrier.Value, '2')
                app.EditSubcarrierIndex.Visible = 'on';
                app.TextSubcarrierLeader.Visible = 'on';
            else
                app.EditSubcarrierIndex.Visible = 'off';
                app.TextSubcarrierLeader.Visible = 'off';
            end
        end

        % Value changed function: PlotModeSwitch
        function PlotModeSwitchValueChanged(app, event)

            if strcmp(app.PlotModeSwitch.Value, '1')
                app.plotMode = 'c';
                app.BtnNextStream.Visible = 'off';
                app.TextStreamIndex.Visible = 'off';
            else
                app.plotMode = 's';
                app.BtnNextStream.Visible = 'on';
                app.TextStreamIndex.Visible = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigureMain = uifigure;
            app.UIFigureMain.Name = 'Wireal';
            app.UIFigureMain.CloseRequestFcn = createCallbackFcn(app, @UIFigureMainCloseRequestFcn, true);
            app.MenuFile = uimenu(app.UIFigureMain);
            app.MenuFile.Separator = 'on';
            app.MenuFile.Text = 'File';
            app.MenuImdel = uimenu(app.MenuFile);
            app.MenuImdel.MenuSelectedFcn = createCallbackFcn(app, @MenuImdel_Callback, true);
            app.MenuImdel.Accelerator = 'I';
            app.MenuImdel.Text = 'Import Model';
            app.MenuView = uimenu(app.UIFigureMain);
            app.MenuView.Text = 'View';
            app.MenuHelp = uimenu(app.UIFigureMain);
            app.MenuHelp.Text = 'Help';
            app.MenuGuide = uimenu(app.MenuHelp);
            app.MenuGuide.Accelerator = 'G';
            app.MenuGuide.Text = 'Getting Started';
            app.MenuAbout = uimenu(app.MenuHelp);
            app.MenuAbout.Accelerator = 'A';
            app.MenuAbout.Text = 'About';
            app.AxesMain = uiaxes(app.UIFigureMain);
            xlabel(app.AxesMain, 'Time [s]')
            ylabel(app.AxesMain, 'SNR [dB]')
            app.AxesMain.XLim = [0 14];
            app.AxesMain.YLim = [0 30];
            app.lstBoxState = uilistbox(app.UIFigureMain);
            app.lstBoxState.Items = {};
            app.lstBoxState.Value = {};
            app.PanelNetwork = uipanel(app.UIFigureMain);
            app.PanelNetwork.Title = 'NETWORK';
            app.TextAddr = uilabel(app.PanelNetwork);
            app.TextAddr.Text = 'Address';
            app.EditIP = uieditfield(app.PanelNetwork, 'text');
            app.EditIP.ValueChangedFcn = createCallbackFcn(app, @EditIP_Callback, true);
            app.EditIP.Tooltip = {'IP address'; ''};
            app.EditIP.Value = '0.0.0.0';
            app.TextPort = uilabel(app.PanelNetwork);
            app.TextPort.Text = 'Port';
            app.EditPort = uieditfield(app.PanelNetwork, 'text');
            app.EditPort.ValueChangedFcn = createCallbackFcn(app, @EditPort_Callback, true);
            app.EditPort.Value = '8888';
            app.PanelWorkspace = uipanel(app.UIFigureMain);
            app.PanelWorkspace.Title = 'WORKSPACE';
            app.TextDir = uilabel(app.PanelWorkspace);
            app.TextDir.Text = '/Working Directory/';
            app.BtnSelectDir = uibutton(app.PanelWorkspace, 'push');
            app.BtnSelectDir.ButtonPushedFcn = createCallbackFcn(app, @BtnSelectDir_Callback, true);
            app.BtnSelectDir.Text = 'SELECT';
            app.PanelData = uipanel(app.UIFigureMain);
            app.PanelData.Title = 'DATA';
            app.TextNtx = uilabel(app.PanelData);
            app.TextNtx.Text = 'Ntx';
            app.EditNtx = uieditfield(app.PanelData, 'text');
            app.EditNtx.ValueChangedFcn = createCallbackFcn(app, @EditNtx_Callback, true);
            app.EditNtx.Value = '1';
            app.TextNrx = uilabel(app.PanelData);
            app.TextNrx.Text = 'Nrx';
            app.EditNrx = uieditfield(app.PanelData, 'text');
            app.EditNrx.ValueChangedFcn = createCallbackFcn(app, @EditNrx_Callback, true);
            app.EditNrx.Value = '3';
            app.TextHz = uilabel(app.PanelData);
            app.TextHz.Text = 'Packet Sent Rate (Hz)';
            app.EditHz = uieditfield(app.PanelData, 'text');
            app.EditHz.ValueChangedFcn = createCallbackFcn(app, @EditHz_Callback, true);
            app.EditHz.Value = '500';
            app.PanelCtrl = uipanel(app.UIFigureMain);
            app.PanelCtrl.Title = 'CONTROL';
            app.TextDetectStreamIndex = uilabel(app.PanelCtrl);
            app.TextDetectStreamIndex.Visible = 'off';
            app.TextDetectStreamIndex.Text = '1';
            app.BtnNextDetectStream = uibutton(app.PanelCtrl, 'push');
            app.BtnNextDetectStream.ButtonPushedFcn = createCallbackFcn(app, @BtnNextDetectStream_Callback, true);
            app.BtnNextDetectStream.Visible = 'off';
            app.BtnNextDetectStream.Text = 'Roll';
            app.LabelConnect = uilabel(app.UIFigureMain);
            app.LabelConnect.Visible = 'off';
            app.LabelConnect.Text = 'CONNECT:';
            app.LabelHz = uilabel(app.UIFigureMain);
            app.LabelHz.Visible = 'off';
            app.LabelHz.Text = 'FREQUENCY:';
            app.LabelMIMO = uilabel(app.UIFigureMain);
            app.LabelMIMO.Visible = 'off';
            app.LabelMIMO.Text = 'MIMO:';
            app.DispConnect = uieditfield(app.UIFigureMain, 'text');
            app.DispConnect.Visible = 'off';
            app.DispHz = uieditfield(app.UIFigureMain, 'text');
            app.DispHz.Visible = 'off';
            app.DispMIMO = uieditfield(app.UIFigureMain, 'text');
            app.DispMIMO.Visible = 'off';
            app.PanelPlotMode = uipanel(app.UIFigureMain);
            app.PanelPlotMode.Title = 'STREAM PLOT MODE';
            app.TextStreamIndex = uilabel(app.PanelPlotMode);
            app.TextStreamIndex.Visible = 'off';
            app.TextStreamIndex.Text = '1';
            app.BtnNextStream = uibutton(app.PanelPlotMode, 'push');
            app.BtnNextStream.ButtonPushedFcn = createCallbackFcn(app, @BtnNextStream_Callback, true);
            app.BtnNextStream.Visible = 'off';
            app.BtnNextStream.Text = 'Roll';
            app.PanelSubcarrierOptions = uipanel(app.UIFigureMain);
            app.PanelSubcarrierOptions.Title = 'SUBCARRIER OPTIONS';
            app.EditSubcarrierIndex = uieditfield(app.PanelSubcarrierOptions, 'text');
            app.EditSubcarrierIndex.ValueChangedFcn = createCallbackFcn(app, @EditSubcarrierIndex_Callback, true);
            app.EditSubcarrierIndex.Visible = 'off';
            app.EditSubcarrierIndex.Value = '1';
            app.TextSubcarrierLeader = uilabel(app.PanelSubcarrierOptions);
            app.TextSubcarrierLeader.Visible = 'off';
            app.TextSubcarrierLeader.Text = '>>>>';
            app.DropDownSubcarrier = uidropdown(app.PanelSubcarrierOptions);
            app.DropDownSubcarrier.Items = {'Average Mode', 'Selection Mode'};
            app.DropDownSubcarrier.ItemsData = {'1', '2'};
            app.DropDownSubcarrier.ValueChangedFcn = createCallbackFcn(app, @DropDownSubcarrier_Callback, true);
            app.DropDownSubcarrier.Value = '1';
            app.Text8 = uilabel(app.UIFigureMain);
            app.Text8.Text = 'Max Length';
            app.EditMaxSec = uieditfield(app.UIFigureMain, 'text');
            app.EditMaxSec.ValueChangedFcn = createCallbackFcn(app, @EditMaxSec_Callback, true);
            app.EditMaxSec.Value = '20';
            app.Text9 = uilabel(app.UIFigureMain);
            app.Text9.Text = '(s)';
            app.BtnStart = uibutton(app.UIFigureMain, 'push');
            app.BtnStart.ButtonPushedFcn = createCallbackFcn(app, @BtnStart_Callback, true);
            app.BtnStart.Text = 'START';
            app.BtnContinue = uibutton(app.UIFigureMain, 'push');
            app.BtnContinue.ButtonPushedFcn = createCallbackFcn(app, @BtnContinue_Callback, true);
            app.BtnContinue.Visible = 'off';
            app.BtnContinue.Text = 'CONTINUE';
            app.BtnPause = uibutton(app.UIFigureMain, 'push');
            app.BtnPause.ButtonPushedFcn = createCallbackFcn(app, @BtnPause_Callback, true);
            app.BtnPause.Visible = 'off';
            app.BtnPause.Text = 'PAUSE';
            app.CheckboxTemp = uicheckbox(app.UIFigureMain);
            app.CheckboxTemp.ValueChangedFcn = createCallbackFcn(app, @CheckboxTemp_Callback, true);
            app.CheckboxTemp.Text = 'Temporary';
            app.BtnCut = uibutton(app.UIFigureMain, 'push');
            app.BtnCut.ButtonPushedFcn = createCallbackFcn(app, @BtnCut_Callback, true);
            app.BtnCut.Visible = 'off';
            app.BtnCut.Text = 'CUT';
            app.Text25 = uilabel(app.UIFigureMain);
            app.Text25.Text = 'Axis Range';
            app.EditAxisLow = uieditfield(app.UIFigureMain, 'text');
            app.EditAxisLow.ValueChangedFcn = createCallbackFcn(app, @EditAxisLow_Callback, true);
            app.EditAxisLow.Value = '0';
            app.Text26 = uilabel(app.UIFigureMain);
            app.Text26.Text = 'to';
            app.EditAxisHigh = uieditfield(app.UIFigureMain, 'text');
            app.EditAxisHigh.ValueChangedFcn = createCallbackFcn(app, @EditAxisHigh_Callback, true);
            app.EditAxisHigh.Value = '30';
            app.TextTempWarn = uilabel(app.UIFigureMain);
            app.TextTempWarn.Visible = 'off';
            app.TextTempWarn.Text = '(DATA WON''T BE STORED)';
            app.CheckboxVitalDetect = uicheckbox(app.UIFigureMain);
            app.CheckboxVitalDetect.ValueChangedFcn = createCallbackFcn(app, @CheckboxVitalDetect_Callback, true);
            app.CheckboxVitalDetect.Text = 'Vital Signs Detection';
            app.LabelRunningTime = uilabel(app.UIFigureMain);
            app.LabelRunningTime.Visible = 'off';
            app.LabelRunningTime.Text = 'RUNNING TIME:';
            app.TextRunningTime = uilabel(app.UIFigureMain);
            app.TextRunningTime.Visible = 'off';
            app.TextRunningTime.Text = '0 d  0 h  0 m  0 s';
            app.PlotModeSwitchLabel = uilabel(app.UIFigureMain);
            app.PlotModeSwitchLabel.Text = {'Plot Mode'; ''};
            app.PlotModeSwitch = uiswitch(app.UIFigureMain, 'slider');
            app.PlotModeSwitch.Items = {'Combine', 'Split'};
            app.PlotModeSwitch.ItemsData = {'1', '2'};
            app.PlotModeSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotModeSwitchValueChanged, true);
            app.PlotModeSwitch.Value = '1';
            app.TabGroup = uitabgroup(app.UIFigureMain);
            app.VitalSignsTab = uitab(app.TabGroup);
            app.VitalSignsTab.Title = 'Vital Signs';
            app.AxesRes = uiaxes(app.VitalSignsTab);
            title(app.AxesRes, 'Decoded Respiration Waveform')
            xlabel(app.AxesRes, 'Time [s]')
            app.AxesRes.XTick = [];
            app.AxesHeart = uiaxes(app.VitalSignsTab);
            title(app.AxesHeart, 'Decoded Heartbeat Waveform')
            xlabel(app.AxesHeart, 'Time [s]')
            app.AxesHeart.XTick = [];
            app.TextBreathRateTag = uilabel(app.VitalSignsTab);
            app.TextBreathRateTag.Text = {'Respiration Rate :'; '(Breaths/Min)'};
            app.TextResRateValue = uilabel(app.VitalSignsTab);
            app.TextResRateValue.Text = 'Suspend';
            app.TextHeartbeatRateTag = uilabel(app.VitalSignsTab);
            app.TextHeartbeatRateTag.Text = {'Heartbeat Rate :'; '(Beats/Min)'};
            app.TextHeartbeatRateValue = uilabel(app.VitalSignsTab);
            app.TextHeartbeatRateValue.Text = 'Suspend';
            app.TextResStatics = uilabel(app.VitalSignsTab);
            app.TextResStatics.Visible = 'off';
            app.TextResStatics.Text = 'Respiration statics';
            app.TextHeartbeatStatics = uilabel(app.VitalSignsTab);
            app.TextHeartbeatStatics.Visible = 'off';
            app.TextHeartbeatStatics.Text = 'Heartbeat statics';
            app.VoiceTab = uitab(app.TabGroup);
            app.VoiceTab.Title = 'Voice';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Wireal_1_6_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigureMain)

            % Execute the startup function
            runStartupFcn(app, @uiFigureMainStartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigureMain)
        end
    end
end